classdef PowerAttackOptimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DD35PowerAttackOptimizerbyOrionvBUIFigure  matlab.ui.Figure
        SaveMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        AttackDataPanel               matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        NofAttacksEditField           matlab.ui.control.NumericEditField
        NofAttacksEditFieldLabel      matlab.ui.control.Label
        BABEditFieldLabel             matlab.ui.control.Label
        BABEditField                  matlab.ui.control.NumericEditField
        AttackBonusEditFieldLabel     matlab.ui.control.Label
        AttackBonusEditField          matlab.ui.control.EditField
        TwoHandedEditFieldLabel       matlab.ui.control.Label
        TwoHandedEditField            matlab.ui.control.EditField
        CritRangeEditFieldLabel       matlab.ui.control.Label
        CritRangeEditField            matlab.ui.control.EditField
        CritMultiplierEditFieldLabel  matlab.ui.control.Label
        CritMultiplierEditField       matlab.ui.control.EditField
        CritBonusEditFieldLabel       matlab.ui.control.Label
        CritBonusEditField            matlab.ui.control.EditField
        DamageEditFieldLabel          matlab.ui.control.Label
        DamageEditField               matlab.ui.control.EditField
        ExtraDamageEditFieldLabel     matlab.ui.control.Label
        ExtraDamageEditField          matlab.ui.control.EditField
        TargetDataPanel               matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        DREditField                   matlab.ui.control.EditField
        DREditFieldLabel              matlab.ui.control.Label
        MissChanceEditField           matlab.ui.control.NumericEditField
        MissChanceLabel               matlab.ui.control.Label
        ArmorClassEditField           matlab.ui.control.NumericEditField
        ArmorClassEditFieldLabel      matlab.ui.control.Label
        ShowGraphButton               matlab.ui.control.StateButton
        ResultsPanel                  matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        IterationsEditField           matlab.ui.control.NumericEditField
        IterationsEditFieldLabel      matlab.ui.control.Label
        OptimizeButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        fig % figure handler
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(gcf); app.fig = uifigure(); delete(app.fig);
            
            pool = gcp('nocreate');
            if isempty(pool); parpool('threads'); end
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            try
                AB = eval("[" + app.AttackBonusEditField.Value + "]");
                TwoHand = eval("[" + app.TwoHandedEditField.Value + "]");
                critrange = eval("[" + app.CritRangeEditField.Value + "]");
                critmult = eval("[" + app.CritMultiplierEditField.Value + "]");
                critconfirmbonus = eval("[" + app.CritBonusEditField.Value + "]");
                multdmg = eval("[" + parsedie(app.DamageEditField.Value) + "]");
                nonmultdmg = eval("[" + parsedie(app.ExtraDamageEditField.Value) + "]");
                numAtks = app.NofAttacksEditField.Value;
                BAB = app.BABEditField.Value;
                AC = app.ArmorClassEditField.Value;
                miss = app.MissChanceEditField.Value;
                DR = eval("[" + app.DREditField.Value + "]");
                iterations = app.IterationsEditField.Value;
            catch
                app.Label_3.Text = 'ERROR';
                app.Label_2.Text = '';
            end
            
            try
                [result1, result2, graphfig] = PAopt(AB, TwoHand, critrange, critmult, critconfirmbonus, multdmg, nonmultdmg, numAtks, BAB, AC, miss, DR, iterations);
                app.Label_3.Text = result1;
                app.Label_2.Text = result2;
                app.fig = graphfig;
                if (app.ShowGraphButton.Value)
                    set(app.fig, 'Visible', 'on');
                end
            catch
                app.Label_3.Text = 'ERROR';
                app.Label_2.Text = '';
            end
        end

        % Value changed function: ShowGraphButton
        function ShowGraphButtonValueChanged(app, event)
            buttonDown = app.ShowGraphButton.Value;
            
            if (buttonDown)
                app.ShowGraphButton.Text = 'Hide Graph';
                if (isvalid(app.fig))
                    set(app.fig, 'Visible', 'on');
                else
                    app.Label_3.Text = 'ERROR';
                    app.Label_2.Text = 'Input attack data first';
                    app.ShowGraphButton.Value = false; app.ShowGraphButton.Text = 'Show Graph';
                end
            else
                app.ShowGraphButton.Text = 'Show Graph';
                if (isvalid(app.fig))
                    set(app.fig, 'Visible', 'off');
                end
            end
            
            
        end

        % Menu selected function: SaveMenu
        function SaveButton(app, event)
            AB = app.AttackBonusEditField.Value;
            TwoHand = app.TwoHandedEditField.Value;
            critrange = app.CritRangeEditField.Value;
            critmult = app.CritMultiplierEditField.Value;
            critconfirmbonus = app.CritBonusEditField.Value;
            multdmg = app.DamageEditField.Value;
            nonmultdmg = app.ExtraDamageEditField.Value;
            numAtks = app.NofAttacksEditField.Value;
            BAB = app.BABEditField.Value;
            AC = app.ArmorClassEditField.Value;
            miss = app.MissChanceEditField.Value;
            DR = app.DREditField.Value;
            iterations = app.IterationsEditField.Value;
            
            savePA(AB, TwoHand, critrange, critmult, critconfirmbonus, multdmg, nonmultdmg, numAtks, BAB, AC, DR, miss, iterations);
            
            drawnow;
            figure(app.DD35PowerAttackOptimizerbyOrionvBUIFigure);
        end

        % Menu selected function: LoadMenu
        function LoadButton(app, event)
            loadPA();
            
            drawnow;
            figure(app.DD35PowerAttackOptimizerbyOrionvBUIFigure);
            
            app.AttackBonusEditField.Value = AB;
            app.TwoHandedEditField.Value = TwoHand;
            app.CritRangeEditField.Value = critrange;
            app.CritMultiplierEditField.Value = critmult;
            app.CritBonusEditField.Value = critconfirmbonus;
            app.DamageEditField.Value = multdmg;
            app.ExtraDamageEditField.Value = nonmultdmg;
            app.NofAttacksEditField.Value = numAtks;
            app.BABEditField.Value = BAB;
            app.ArmorClassEditField.Value = AC;
            app.MissChanceEditField.Value = miss;
            app.DREditField.Value = DR;
            app.IterationsEditField.Value = iterations;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DD35PowerAttackOptimizerbyOrionvBUIFigure = uifigure('Visible', 'off');
            app.DD35PowerAttackOptimizerbyOrionvBUIFigure.Name = 'D&D 3.5 Power Attack Optimizer - by OrionvB';
            app.DD35PowerAttackOptimizerbyOrionvBUIFigure.Icon = 'icon.png';
            app.SaveMenu = uimenu(app.DD35PowerAttackOptimizerbyOrionvBUIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButton, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.DD35PowerAttackOptimizerbyOrionvBUIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButton, true);
            app.LoadMenu.Text = 'Load';
            app.GridLayout = uigridlayout(app.DD35PowerAttackOptimizerbyOrionvBUIFigure);
            app.OptimizeButton = uibutton(app.GridLayout, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Tooltip = {'Run simulation (may take a few seconds to complete)'};
            app.OptimizeButton.Text = 'Optimize';
            app.IterationsEditFieldLabel = uilabel(app.GridLayout);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.GridLayout, 'numeric');
            app.IterationsEditField.Limits = [1000 Inf];
            app.IterationsEditField.Tooltip = {'Number of simulated attack rolls for each possible Power Attack value. (Higher values gives more accurate results but take longer to compute)'};
            app.IterationsEditField.Value = 100000;
            app.ResultsPanel = uipanel(app.GridLayout);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout3 = uigridlayout(app.ResultsPanel);
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            app.ShowGraphButton = uibutton(app.GridLayout, 'state');
            app.ShowGraphButton.ValueChangedFcn = createCallbackFcn(app, @ShowGraphButtonValueChanged, true);
            app.ShowGraphButton.Text = 'Show Graph';
            app.TargetDataPanel = uipanel(app.GridLayout);
            app.TargetDataPanel.Title = 'Target Data';
            app.GridLayout4 = uigridlayout(app.TargetDataPanel);
            app.ArmorClassEditFieldLabel = uilabel(app.GridLayout4);
            app.ArmorClassEditFieldLabel.Text = 'Armor Class';
            app.ArmorClassEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ArmorClassEditField.Limits = [1 Inf];
            app.ArmorClassEditField.Tooltip = {'Target''s Armor Class'};
            app.ArmorClassEditField.Value = 10;
            app.MissChanceLabel = uilabel(app.GridLayout4);
            app.MissChanceLabel.Text = 'Miss Chance';
            app.MissChanceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MissChanceEditField.Limits = [0 100];
            app.MissChanceEditField.Tooltip = {'Target''s Miss Chance in %'};
            app.DREditFieldLabel = uilabel(app.GridLayout4);
            app.DREditFieldLabel.Text = 'DR';
            app.DREditField = uieditfield(app.GridLayout4, 'text');
            app.DREditField.Tooltip = {'Target''s Damage Reduction applied to your attacks (written as a vector of integers)'};
            app.DREditField.Value = '0';
            app.AttackDataPanel = uipanel(app.GridLayout);
            app.AttackDataPanel.Title = 'Attack Data';
            app.GridLayout2 = uigridlayout(app.AttackDataPanel);
            app.ExtraDamageEditField = uieditfield(app.GridLayout2, 'text');
            app.ExtraDamageEditField.Tooltip = {'Damage of your attacks that are NOT multiplied in case of critical hit (written as a vector of standard dice notation "AdX+B")'};
            app.ExtraDamageEditFieldLabel = uilabel(app.GridLayout2);
            app.ExtraDamageEditFieldLabel.Text = 'Extra Damage';
            app.DamageEditField = uieditfield(app.GridLayout2, 'text');
            app.DamageEditField.Tooltip = {'Damage of your attacks (written as a vector of standard dice notation "AdX+B")'};
            app.DamageEditFieldLabel = uilabel(app.GridLayout2);
            app.DamageEditFieldLabel.Text = 'Damage';
            app.CritBonusEditField = uieditfield(app.GridLayout2, 'text');
            app.CritBonusEditField.Tooltip = {'Bonus to Critical Confirmation rolls of your attacks (written as a vector of integers)'};
            app.CritBonusEditFieldLabel = uilabel(app.GridLayout2);
            app.CritBonusEditFieldLabel.Text = 'Crit Bonus';
            app.CritMultiplierEditField = uieditfield(app.GridLayout2, 'text');
            app.CritMultiplierEditField.Tooltip = {'Critical Hit multipliers of your attacks (written as a vector of integers)'};
            app.CritMultiplierEditFieldLabel = uilabel(app.GridLayout2);
            app.CritMultiplierEditFieldLabel.Text = 'Crit Multiplier';
            app.CritRangeEditField = uieditfield(app.GridLayout2, 'text');
            app.CritRangeEditField.Tooltip = {'Critical Range of your attacks. Minimum roll that causes your attacks to crit (written as a vector of integers)'};
            app.CritRangeEditFieldLabel = uilabel(app.GridLayout2);
            app.CritRangeEditFieldLabel.Text = 'Crit Range';
            app.TwoHandedEditField = uieditfield(app.GridLayout2, 'text');
            app.TwoHandedEditField.Tooltip = {'Two Handedness of your attacks (written as a vector of booleans)'};
            app.TwoHandedEditFieldLabel = uilabel(app.GridLayout2);
            app.TwoHandedEditFieldLabel.Text = 'Two Handed';
            app.AttackBonusEditField = uieditfield(app.GridLayout2, 'text');
            app.AttackBonusEditField.Tooltip = {'Attack Bonus of your attacks (written as a vector of integers)'};
            app.AttackBonusEditFieldLabel = uilabel(app.GridLayout2);
            app.AttackBonusEditFieldLabel.Text = 'Attack Bonus';
            app.BABEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BABEditField.Limits = [1 Inf];
            app.BABEditField.Tooltip = {'Your Base Attack Bonus'};
            app.BABEditField.Value = 1;
            app.BABEditFieldLabel = uilabel(app.GridLayout2);
            app.BABEditFieldLabel.Text = 'BAB';
            app.NofAttacksEditFieldLabel = uilabel(app.GridLayout2);
            app.NofAttacksEditFieldLabel.Text = 'Nº of Attacks';
            app.NofAttacksEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NofAttacksEditField.Limits = [1 Inf];
            app.NofAttacksEditField.Tooltip = {'Number of attacks to make'};
            app.NofAttacksEditField.Value = 1;
            % Show the figure after all components are created
            app.DD35PowerAttackOptimizerbyOrionvBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerAttackOptimizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DD35PowerAttackOptimizerbyOrionvBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DD35PowerAttackOptimizerbyOrionvBUIFigure)
        end
    end
end